classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        EditField_kd    matlab.ui.control.NumericEditField
        EditField_kp    matlab.ui.control.NumericEditField
        Label_kd        matlab.ui.control.Label
        Label_kp        matlab.ui.control.Label
        StartButton     matlab.ui.control.Button
        EditField_Vz    matlab.ui.control.NumericEditField
        EditField_t     matlab.ui.control.NumericEditField
        Label_Vz        matlab.ui.control.Label
        Label_t         matlab.ui.control.Label
        ButtonGroup_2   matlab.ui.container.ButtonGroup
        Button_Full     matlab.ui.control.RadioButton
        Button_Reg      matlab.ui.control.RadioButton
        Button_Braking  matlab.ui.control.RadioButton
        Button_Racing   matlab.ui.control.RadioButton
        Spinner         matlab.ui.control.Spinner
        Label_5         matlab.ui.control.Label
        EditField_P0    matlab.ui.control.NumericEditField
        EditField_V0    matlab.ui.control.NumericEditField
        EditField_x0    matlab.ui.control.NumericEditField
        EditField_dt    matlab.ui.control.NumericEditField
        Label_P0        matlab.ui.control.Label
        Label_V0        matlab.ui.control.Label
        Label_x0        matlab.ui.control.Label
        Label_dt        matlab.ui.control.Label
        Label_4         matlab.ui.control.Label
        LabelV          matlab.ui.control.Label
        LabelV2max      matlab.ui.control.Label
        LabelV1max      matlab.ui.control.Label
        EditFieldV1max  matlab.ui.control.EditField
        EditFieldV2max  matlab.ui.control.EditField
        Label           matlab.ui.control.Label
        EditFieldV      matlab.ui.control.EditField
        EditFieldN      matlab.ui.control.EditField
        Label_3         matlab.ui.control.Label
        EditFieldW      matlab.ui.control.EditField
        Label_2         matlab.ui.control.Label
        ButtonGroup     matlab.ui.container.ButtonGroup
        Button_KPK      matlab.ui.control.RadioButton
        Button_Kater    matlab.ui.control.RadioButton
        Button_Paris    matlab.ui.control.RadioButton
        UIAxes_p        matlab.ui.control.UIAxes
        UIAxes_v        matlab.ui.control.UIAxes
        UIAxes_x        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        W % Водоизмещение, кг
        N % Мощность, Вт
        Vmax % Скорость, м/с
        Vk % м/с
        V1max
        V2max
        dt % шаг моделирования
        x0 % нач. положение
        V0 % нач. скорость
        P0 % нач. мощность
        Pmax % макс. тяга
        Flag_mode % Режим работы
        Flag_ship % Тип судна
        t_mod % время моделирования
        Vz %заданная скорость
        kp
        kd
        flag_error
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, a)
       app.Button_Paris.Value = false;  
       app.Button_Kater.Value = false;
       app.Button_KPK.Value = false;
       app.EditFieldV1max.Visible = 0;
       app.EditFieldV2max.Visible = 0;
       app.LabelV1max.Visible=0;
       app.LabelV2max.Visible=0;
       app.Label_Vz.Visible = false;
       app.Label_t.Visible = false;
       app.EditField_Vz.Visible = false;
       app.EditField_t.Visible = false;
       app.UIAxes_p.Visible = false;
       app.UIAxes_v.Visible = false;
       app.UIAxes_x.Visible = false;
       app.Label_kd.Visible = false;
       app.Label_kp.Visible = false;
       app.EditField_kp.Visible = false;
       app.EditField_kd.Visible = false;
       %инициализация
       app.W = 0;
       app.N = 0;
       app.Vmax = 0;
       app.Vk = 0;
       app.V1max = 0;
       app.V2max = 0;  
       app.Flag_mode = 0;
       helpdlg('Выберите судно!','Help');
        end

        % Selection changed function: ButtonGroup
        function Ship(app, event)
             if app.Button_Paris.Value == true
                 app.EditFieldW.Value = '30395'; 
                 app.EditFieldN.Value = '61000';
                 app.EditFieldV.Value = '21.5';
                 app.EditFieldV1max.Visible = 0;
                 app.EditFieldV2max.Visible = 0;
                 app.LabelV1max.Visible=0;
                 app.LabelV2max.Visible=0;
                 app.LabelV.Text='Максимальная скорость, уз';
                 %перевод в си
                 app.W = str2double(app.EditFieldW.Value)*1000;
                 app.N = str2double(app.EditFieldN.Value)*735.5;
                 app.Vmax = str2double(app.EditFieldV.Value)*0.51;
                 app.Flag_ship = 1;
                 app.flag_error = 1;
             end
             if app.Button_Kater.Value == true
                 app.EditFieldW.Value = '4.6'; 
                 app.EditFieldN.Value = '50';
                 app.EditFieldV.Value = '9.0';  
                 app.EditFieldV1max.Visible = 0;
                 app.EditFieldV2max.Visible = 0;
                 app.LabelV1max.Visible=0;
                 app.LabelV2max.Visible=0;
                 app.LabelV.Text='Максимальная скорость, уз';
                 app.W = str2double(app.EditFieldW.Value)*1000;
                 app.N = str2double(app.EditFieldN.Value)*735.5;
                 app.Vmax = str2double(app.EditFieldV.Value)*0.51;
                 app.Flag_ship = 2;
                 app.flag_error = 1;
             end
             if app.Button_KPK.Value == true
                 app.EditFieldW.Value = '23.0'; 
                 app.EditFieldN.Value = '800';
                 app.EditFieldV.Value = '9.0';
                 app.EditFieldV1max.Visible = 1;
                 app.EditFieldV2max.Visible = 1;
                 app.LabelV1max.Visible=1;
                 app.LabelV2max.Visible=1;
                 app.LabelV.Text='Vk, уз';
                 app.EditFieldV1max.Value = '10.8';
                 app.EditFieldV2max.Value = '26';
                 app.W = str2double(app.EditFieldW.Value)*1000;
                 app.N = str2double(app.EditFieldN.Value)*735.5;
                 app.Vk = str2double(app.EditFieldV.Value)*0.51;
                 app.V1max = str2double(app.EditFieldV1max.Value)*0.51;
                 app.V2max = str2double(app.EditFieldV2max.Value)*0.51;
                 app.Flag_ship = 3;
                 app.flag_error = 1;
             end
             helpdlg('Настройте параметры моделирования!','Help');
        end

        % Value changed function: EditField_dt
        function dtValueChanged(app, event)
            if app.flag_error == 1
            value = app.EditField_dt.Value;
            app.dt = value;
            else
            helpdlg('Сначала выберите судно!','Help');
            end
        end

        % Value changed function: EditField_x0
        function x0ValueChanged(app, event)
            if app.flag_error == 1
            value = app.EditField_x0.Value;
            app.x0 = value;
            else
            helpdlg('Сначала выберите судно!','Help');
            end
        end

        % Value changed function: EditField_V0
        function V0ValueChanged(app, event)
            if app.flag_error == 1
            value = app.EditField_V0.Value;
            app.V0 = value;
            else
            helpdlg('Сначала выберите судно!','Help');
            end
        end

        % Value changed function: EditField_P0
        function P0ValueChanged(app, event)
            if app.flag_error == 1
            value = app.EditField_P0.Value;
            app.P0 = value;
            else
            helpdlg('Сначала выберите судно!','Help');
            end
        end

        % Value changed function: Spinner
        function PmaxValueChanged(app, event)
            if app.flag_error == 1
            value = app.Spinner.Value;
            app.Pmax = value;
            else
            helpdlg('Сначала выберите судно!','Help');
            end
        end

        % Selection changed function: ButtonGroup_2
        function mode(app, event)
            if app.Button_Racing.Value == 1
                app.Flag_mode = 1;
                app.Label_Vz.Visible = false;
                app.Label_t.Visible = false;
                app.EditField_Vz.Visible = false;
                app.EditField_t.Visible = false;
                app.Label_kd.Visible = false;
                app.Label_kp.Visible = false;
                app.EditField_kp.Visible = false;
                app.EditField_kd.Visible = false;
            end
            if app.Button_Braking.Value == 1
                app.Flag_mode = 2;
                app.Label_Vz.Visible = false;
                app.Label_t.Visible = false;
                app.EditField_Vz.Visible = false;
                app.EditField_t.Visible = false;
                app.Label_kd.Visible = false;
                app.Label_kp.Visible = false;
                app.EditField_kp.Visible = false;
                app.EditField_kd.Visible = false;
                helpdlg('Задайте начачальные условия!','Help');
            end
            if app.Button_Reg.Value == 1
                app.Flag_mode = 3;
                app.Label_Vz.Visible = true;
                app.Label_t.Visible = true;
                app.EditField_Vz.Visible = true;
                app.EditField_t.Visible = true;
                app.Label_kd.Visible = true;
                app.Label_kp.Visible = true;
                app.EditField_kp.Visible = true;
                app.EditField_kd.Visible = true;
                if (app.Flag_ship == 1)
                    app.EditField_kp.Value = 5;
                    app.EditField_kd.Value = 150;
                end
                if (app.Flag_ship == 2)
                    app.EditField_kp.Value = 7;
                    app.EditField_kd.Value = 25;
                end
                if (app.Flag_ship == 3)
                    app.EditField_kp.Value = 3;
                    app.EditField_kd.Value = 15;
                end
                helpdlg('Задайте время моделирования и требуемую скорость!','Help');
            end
            if app.Button_Full.Value == 1
                app.Flag_mode = 4;
                app.Label_Vz.Visible = false;
                app.Label_t.Visible = false;
                app.EditField_Vz.Visible = false;
                app.EditField_t.Visible = false;
                app.Label_kd.Visible = false;
                app.Label_kp.Visible = false;
                app.EditField_kp.Visible = false;
                app.EditField_kd.Visible = false;
            end
        end

        % Button pushed function: StartButton
        function Start(app, event)
                 if app.Flag_mode ~= 0
                 app.UIAxes_p.Visible = true;
                 app.UIAxes_v.Visible = true;
                 app.UIAxes_x.Visible = true;
                 Fmax = app.N/app.Vmax;% N = F*v
                 A = Fmax/app.Vmax^2;
                 i = 1;
                 t = [0];
                 v = [app.EditField_V0.Value];
                 P = [app.EditField_P0.Value];
                 x = [app.EditField_x0.Value];
                 dx = 0;
                 eps = 0.007;% допустимая погрешность, т.к. идеально не попадем
                 if app.Flag_mode == 4%full
                   if app.Flag_ship == 1%париж
                        dFmax = 0.1*Fmax;
                        app.dt = app.EditField_dt.Value;
                        dPmax = app.dt*dFmax/Fmax*100;
                        dP = 10;
                        if (dP >= dPmax)
                            dP = dPmax;
                        end                    
                        %разгон
                        while ( (app.Vmax-v(i)) > eps)
                            tt = t(i)+app.dt;
                            t = [t tt];
                            xx = x(i)+dx+(P(i)*Fmax*app.dt^2/100-A*dx*abs(dx))/app.W;
                            x = [x xx];
                            vv = (x(i+1)-x(i))/app.dt;
                            v = [v vv];
                            if (P(i) < app.Pmax)
                                P = [P (P(i)+dP)];
                            else
                                P = [P app.Pmax];
                            end
                            i = i + 1;
                            dx = x(i)-x(i-1);   
                            if ((v(i)-v(i-1))<eps/10 && i > 3) %учитывает, что мощность может быть не 100%
                                break
                            end
                        end
                        while ( v(i) > eps)
                            tt = t(i)+app.dt;
                            t = [t tt];
                            xx = x(i)+dx+(P(i)*Fmax*app.dt^2/100-A*dx*abs(dx))/app.W;
                            x = [x xx];
                            vv = (x(i+1)-x(i))/app.dt;
                            v = [v vv];
                            if (P(i) > -app.Pmax)
                                P = [P (P(i)-dP)];
                            else
                                P = [P -app.Pmax];
                            end
                            i = i + 1;
                            dx = x(i)-x(i-1);    
                        end
                   end
                   if app.Flag_ship == 2%катер
                        dFmax = 0.2*Fmax;
                        dPmax = app.dt*dFmax/Fmax*100;
                        dP = 10;
                        if (dP >= dPmax)
                        dP = dPmax;
                        end
                        while ( (app.Vmax-v(i)) > eps)
                            tt = t(i)+app.dt;
                            t = [t tt];
                            xx = x(i)+dx+(P(i)*Fmax*app.dt^2/100-A*dx*abs(dx))/app.W;
                            x = [x xx];
                            vv = (x(i+1)-x(i))/app.dt;
                            v = [v vv];
                            if (P(i) < app.Pmax)
                                P = [P (P(i)+dP)];
                            else
                                P = [P app.Pmax];
                            end
                            i = i + 1;
                            dx = x(i)-x(i-1); 
                            if ((v(i)-v(i-1))<eps/10 && i > 3) %учитывает, что мощность может быть не 100%
                                break
                            end
                        end
                        %торможение
                        while ( v(i) > eps)
                            tt = t(i)+app.dt;
                            t = [t tt];
                            xx = x(i)+dx+(P(i)*Fmax*app.dt^2/100-A*dx*abs(dx))/app.W;
                            x = [x xx];
                            vv = (x(i+1)-x(i))/app.dt;
                            v = [v vv];
                            if (P(i) > -app.Pmax)
                                P = [P (P(i)-dP)];
                            else
                                P = [P -app.Pmax];
                            end
                            i = i + 1;
                            dx = x(i)-x(i-1);    
                        end
                   end
                   if app.Flag_ship == 3%КПК
                        Fmax = app.N/app.V2max;% N = F*v
                        dFmax = 0.2*Fmax;
                        dPmax = app.dt*dFmax/Fmax*100;
                        dP = 10;
                        if (dP >= dPmax)
                            dP = dPmax;
                        end
                        A1 = Fmax/app.V1max^2;
                        A2 = Fmax/app.V2max^2;
                        while ( (app.V2max-v(i)) > eps)
                            if (v(i)<app.Vk)
                                A = Fmax/app.V1max^2;
                            end
                            if ((v(i) >= app.Vk)&& (v(i) <= app.V1max) )
                                A = A1 - (v(i)-app.Vk)*(A1-A2)/(app.V1max-app.Vk);
                            end
                            if (v(i) > app.V1max)
                                A = A2;
                            end
                            tt = t(i)+app.dt;
                            t = [t tt];
                            xx = x(i)+dx+(P(i)*Fmax*app.dt^2/100-A*dx*abs(dx))/app.W;
                            x = [x xx];
                            vv = (x(i+1)-x(i))/app.dt;
                            v = [v vv];
                            if (P(i) < app.Pmax)
                                P = [P (P(i)+dP)];
                            else
                                P = [P app.Pmax];
                            end
                            i = i + 1;
                            dx = x(i)-x(i-1);
                            if ((v(i)-v(i-1))<eps/10 && i > 3) %учитывает, что мощность может быть не 100%
                                break
                            end
                        end
                            %торможение
                        while ( v(i) > eps)
                            if (v(i)<app.Vk)
                                A = Fmax/app.V1max^2;
                            end
                            if ((v(i) >= app.Vk)&& (v(i) <= app.V1max) )
                                A = A1 - (v(i)-app.Vk)*(A1-A2)/(app.V1max-app.Vk);
                            end
                            if (v(i) > app.V1max)
                                A = A2;
                            end
                            tt = t(i)+app.dt;
                            t = [t tt];
                            xx = x(i)+dx+(P(i)*Fmax*app.dt^2/100-A*dx*abs(dx))/app.W;
                            x = [x xx];
                            vv = (x(i+1)-x(i))/app.dt;
                            v = [v vv];
                            if (P(i) > -app.Pmax)
                                P = [P (P(i)-dP)];
                            else
                                P = [P -app.Pmax];
                            end
                            i = i + 1;
                            dx = x(i)-x(i-1);    
                        end
                   end
               end
            if app.Flag_mode == 1%разгон
               if app.Flag_ship == 1%париж
                    Fmax = app.N/app.Vmax;% N = F*v
                    A = Fmax/app.Vmax^2;
                    dFmax = 0.1*Fmax;
                    app.dt = app.EditField_dt.Value;
                    dPmax = app.dt*dFmax/Fmax*100;
                    dP = 10;
                    if (dP >= dPmax)
                        dP = dPmax;
                    end                    
                    %разгон
                    while ( (app.Vmax-v(i)) > eps)
                        tt = t(i)+app.dt;
                        t = [t tt];
                        xx = x(i)+dx+(P(i)*Fmax*app.dt^2/100-A*dx*abs(dx))/app.W;
                        x = [x xx];
                        vv = (x(i+1)-x(i))/app.dt;
                        v = [v vv];
                        if (P(i) < app.Pmax)
                            P = [P (P(i)+dP)];
                        else
                            P = [P app.Pmax];
                        end
                        i = i + 1;
                        dx = x(i)-x(i-1);
                        if ((v(i)-v(i-1))<eps/10 && i > 3) %учитывает, что мощность может быть не 100%
                            break
                        end
                    end
               end
               if app.Flag_ship == 2%катер
                    dFmax = 0.2*Fmax;
                    dPmax = app.dt*dFmax/Fmax*100;
                    dP = 10;
                    if (dP >= dPmax)
                    dP = dPmax;
                    end
                    while ( (app.Vmax-v(i)) > eps)
                        tt = t(i)+app.dt;
                        t = [t tt];
                        xx = x(i)+dx+(P(i)*Fmax*app.dt^2/100-A*dx*abs(dx))/app.W;
                        x = [x xx];
                        vv = (x(i+1)-x(i))/app.dt;
                        v = [v vv];
                        if (P(i) < app.Pmax)
                            P = [P (P(i)+dP)];
                        else
                            P = [P app.Pmax];
                        end
                        i = i + 1;
                        dx = x(i)-x(i-1); 
                        if ((v(i)-v(i-1))<eps/10 && i > 3) %учитывает, что мощность может быть не 100%
                            break
                        end
                    end 
               end
               if app.Flag_ship == 3%КПК
                   Fmax = app.N/app.V2max;% N = F*v
                        dFmax = 0.2*Fmax;
                        dPmax = app.dt*dFmax/Fmax*100;
                        dP = 10;
                        if (dP >= dPmax)
                            dP = dPmax;
                        end
                        A1 = Fmax/app.V1max^2;
                        A2 = Fmax/app.V2max^2;
                        while ( (app.V2max-v(i)) > eps)
                            if (v(i)<app.Vk)
                                A = Fmax/app.V1max^2;
                            end
                            if ((v(i) >= app.Vk)&& (v(i) <= app.V1max) )
                                A = A1 - (v(i)-app.Vk)*(A1-A2)/(app.V1max-app.Vk);
                            end
                            if (v(i) > app.V1max)
                                A = A2;
                            end
                            tt = t(i)+app.dt;
                            t = [t tt];
                            xx = x(i)+dx+(P(i)*Fmax*app.dt^2/100-A*dx*abs(dx))/app.W;
                            x = [x xx];
                            vv = (x(i+1)-x(i))/app.dt;
                            v = [v vv];
                            if (P(i) < app.Pmax)
                                P = [P (P(i)+dP)];
                            else
                                P = [P app.Pmax];
                            end
                            i = i + 1;
                            dx = x(i)-x(i-1);
                            if ((v(i)-v(i-1))<eps/10 && i > 3) %учитывает, что мощность может быть не 100%
                                break
                            end
                        end
               end
            end
            if app.Flag_mode == 2%торможение
               if app.Flag_ship == 1%париж
                    dFmax = 0.1*Fmax;
                    app.dt = app.EditField_dt.Value;
                    dPmax = app.dt*dFmax/Fmax*100;
                    dP = 10;
                    if (dP >= dPmax)
                        dP = dPmax;
                    end        
                    dx = v(i)*app.dt;
                    while ( v(i) > eps)
                            tt = t(i)+app.dt;
                            t = [t tt];
                            xx = x(i)+dx+(P(i)*Fmax*app.dt^2/100-A*dx*abs(dx))/app.W;
                            x = [x xx];
                            vv = (x(i+1)-x(i))/app.dt;
                            v = [v vv];
                            if (P(i) > -app.Pmax)
                                P = [P (P(i)-dP)];
                            else
                                P = [P -app.Pmax];
                            end
                            i = i + 1;
                            dx = x(i)-x(i-1);    
                    end
               end
               if app.Flag_ship == 2%катер
                   dFmax = 0.2*Fmax;
                    dPmax = app.dt*dFmax/Fmax*100;
                    dP = 10;
                    if (dP >= dPmax)
                        dP = dPmax;
                    end
                    dx = v(i)*app.dt;
                   while ( v(i) > eps)
                            tt = t(i)+app.dt;
                            t = [t tt];
                            xx = x(i)+dx+(P(i)*Fmax*app.dt^2/100-A*dx*abs(dx))/app.W;
                            x = [x xx];
                            vv = (x(i+1)-x(i))/app.dt;
                            v = [v vv];
                            if (P(i) > -app.Pmax)
                                P = [P (P(i)-dP)];
                            else
                                P = [P -app.Pmax];
                            end
                            i = i + 1;
                            dx = x(i)-x(i-1);    
                   end
               end
               if app.Flag_ship == 3%КПК
                  Fmax = app.N/app.V2max;% N = F*v
                  dFmax = 0.2*Fmax;
                  dPmax = app.dt*dFmax/Fmax*100;
                  dP = 10;
                  if (dP >= dPmax)
                      dP = dPmax;
                  end
                  A1 = Fmax/app.V1max^2;
                  A2 = Fmax/app.V2max^2; 
                  dx = v(i)*app.dt;
                        while ( v(i) > eps)
                            if (v(i)<app.Vk)
                                A = Fmax/app.V1max^2;
                            end
                            if ((v(i) >= app.Vk)&& (v(i) <= app.V1max) )
                                A = A1 - (v(i)-app.Vk)*(A1-A2)/(app.V1max-app.Vk);
                            end
                            if (v(i) > app.V1max)
                                A = A2;
                            end
                            tt = t(i)+app.dt;
                            t = [t tt];
                            xx = x(i)+dx+(P(i)*Fmax*app.dt^2/100-A*dx*abs(dx))/app.W;
                            x = [x xx];
                            vv = (x(i+1)-x(i))/app.dt;
                            v = [v vv];
                            if (P(i) > -app.Pmax)
                                P = [P (P(i)-dP)];
                            else
                                P = [P -app.Pmax];
                            end
                            i = i + 1;
                            dx = x(i)-x(i-1);    
                        end                  
               end
            end
            if app.Flag_mode == 3%регулятор
                app.kp = app.EditField_kp.Value;% коэф ПД регулятора
                app.kd = app.EditField_kd.Value;% коэф ПД регулятора
               if app.Flag_ship == 1%париж
                    dFmax = 0.1*Fmax;
                    dPmax = app.dt*dFmax/Fmax*100;
                    prev_e = app.Vz-v(1);
                    while(t(i) < app.t_mod)
                        t = [t t(i)+app.dt];
                        xx = x(i)+dx+((P(i)*Fmax*app.dt^2)/100-A*dx*abs(dx))/app.W;
                        x = [x xx];
                        vv = (x(i+1)-x(i))/app.dt;
                        v = [v vv];
                        e = app.Vz - v(i);
                        de = (e-prev_e)/app.dt;
                        dP = app.kp*e+app.kd*de;
                        prev_e = e;
                        if (abs(dP) >= dPmax)
                        dP = dPmax;
                        end
                        if (P(i)+dP > app.Pmax)
                            P = [P app.Pmax];
                        end
                        if (P(i)+dP <= app.Pmax)
                            P = [P P(i)+dP];
                        end
                        i = i+1;
                        dx = x(i)-x(i-1);
                    end
               end
               if app.Flag_ship == 2%катер
                    dFmax = 0.2*Fmax;
                    dPmax = app.dt*dFmax/Fmax*100;
                    app.kp = 7;
                    app.kd = 25;
                    dx = 0;
                    prev_e = app.Vz-v(1);
                    while(t(i) < app.t_mod)
                        tt = t(i)+app.dt;
                        t = [t tt];
                        xx = x(i)+dx+(P(i)*Fmax*app.dt^2/100-A*dx*abs(dx))/app.W;
                        x = [x xx];
                        vv = (x(i+1)-x(i))/app.dt;
                        v = [v vv];
                        e = app.Vz - v(i);
                        de = (e-prev_e)/app.dt;
                        dP = app.kp*e+app.kd*de;
                        prev_e = e;
                        if (abs(dP) >= dPmax)
                            dP = dPmax;
                        end
                        if (P(i)+dP > app.Pmax)
                            P = [P app.Pmax];
                        end
                        if (P(i)+dP <= app.Pmax)
                            P = [P P(i)+dP];
                        end  
                        i = i+1;
                        dx = x(i)-x(i-1);
                    end
               end
               if app.Flag_ship == 3%КПК
                    Fmax = app.N/app.V2max;% N = F*v
                    dFmax = 0.2*Fmax;
                    dPmax = app.dt*dFmax/Fmax*100;
                    app.kp = 3;
                    app.kd = 15;
                    A1 = Fmax/app.V1max^2;
                    A2 = Fmax/app.V2max^2;
                    prev_e = app.Vz-v(1);
                    while(t(i) < app.t_mod)
                        if (v(i)<app.Vk)
                            A = Fmax/app.V1max^2;
                        end
                        if ((v(i) >= app.Vk)&& (v(i) <= app.V1max) )
                            A = A1 - (v(i)-app.Vk)*(A1-A2)/(app.V1max-app.Vk);
                        end
                        if (v(i) > app.V1max)
                            A = A2;
                        end
                        tt = t(i)+app.dt;
                        t = [t tt];
                        xx = x(i)+dx+(P(i)*Fmax*app.dt^2/100-A*dx*abs(dx))/app.W;
                        x = [x xx];
                        vv = (x(i+1)-x(i))/app.dt;
                        v = [v vv];
                        e = app.Vz - v(i);
                        de = (e-prev_e)/app.dt;
                        dP = app.kp*e+app.kd*de;
                        prev_e = e;
                        if (abs(dP) >= dPmax)
                        dP = dPmax;
                        end
                        if (P(i)+dP > app.Pmax)
                            P = [P app.Pmax];
                        end
                        if (P(i)+dP <= app.Pmax)
                            P = [P P(i)+dP];
                        end  
                        i = i + 1;
                        dx = x(i)-x(i-1);      
                    end
               end
            end
            plot(app.UIAxes_x,t,x,'Linewidth',1.5); 
            xlabel(app.UIAxes_x,'t, c','FontSize',12,'FontWeight','bold'); 
            ylabel(app.UIAxes_x,'x, м','FontSize',12,'FontWeight','bold');
            plot(app.UIAxes_v,t,v,'Linewidth',1.5);
            xlabel(app.UIAxes_v,'t, c','FontSize',12,'FontWeight','bold'); 
            ylabel(app.UIAxes_v,'v, м/с','FontSize',12,'FontWeight','bold');
            plot(app.UIAxes_p,t,P,'Linewidth',1.5);
            xlabel(app.UIAxes_p,'t, c','FontSize',12,'FontWeight','bold'); 
            ylabel(app.UIAxes_p,'P,%','FontSize',12,'FontWeight','bold');
                 else
                     helpdlg('Выберите режим работы','Help');
                 end
        end

        % Value changed function: EditField_t
        function tValueChanged(app, event)
            value = app.EditField_t.Value;
            app.t_mod = value;
        end

        % Value changed function: EditField_Vz
        function VzValueChanged(app, event)
            value = app.EditField_Vz.Value;
            app.Vz = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_x = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_x, 'X')
            ylabel(app.UIAxes_x, 'Y')
            zlabel(app.UIAxes_x, 'Z')
            app.UIAxes_x.Tag = 'UIAxes_x';
            app.UIAxes_v = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_v, 'X')
            ylabel(app.UIAxes_v, 'Y')
            zlabel(app.UIAxes_v, 'Z')
            app.UIAxes_v.Tag = 'UIAxes_v';
            app.UIAxes_p = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_p, 'X')
            ylabel(app.UIAxes_p, 'Y')
            zlabel(app.UIAxes_p, 'Z')
            app.UIAxes_p.Tag = 'UIAxes_p';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Ship, true);
            app.ButtonGroup.Title = 'Тип судна';
            app.ButtonGroup.Tag = 'ButtonGroup';
            app.Button_Paris = uiradiobutton(app.ButtonGroup);
            app.Button_Paris.Tag = 'Button_Paris';
            app.Button_Paris.Text = '«Парижская Коммуна»';
            app.Button_Paris.Value = true;
            app.Button_Kater = uiradiobutton(app.ButtonGroup);
            app.Button_Kater.Tag = 'Button_Kater';
            app.Button_Kater.Text = 'Малый рабочий катер';
            app.Button_KPK = uiradiobutton(app.ButtonGroup);
            app.Button_KPK.Tag = 'Button_KPK';
            app.Button_KPK.Text = 'КПК';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Водоизмещение, т';
            app.EditFieldW = uieditfield(app.UIFigure, 'text');
            app.EditFieldW.Tag = 'EditFieldW';
            app.EditFieldW.Editable = 'off';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Мощность ГЭУ, лс';
            app.EditFieldN = uieditfield(app.UIFigure, 'text');
            app.EditFieldN.Tag = 'EditFieldN';
            app.EditFieldN.Editable = 'off';
            app.EditFieldV = uieditfield(app.UIFigure, 'text');
            app.EditFieldV.Tag = 'EditFieldV';
            app.EditFieldV.Editable = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Tag = 'Label';
            app.Label.Text = 'Параметры судна';
            app.EditFieldV2max = uieditfield(app.UIFigure, 'text');
            app.EditFieldV2max.Tag = 'EditFieldV2max';
            app.EditFieldV2max.Editable = 'off';
            app.EditFieldV1max = uieditfield(app.UIFigure, 'text');
            app.EditFieldV1max.Tag = 'EditFieldV1max';
            app.EditFieldV1max.Editable = 'off';
            app.LabelV1max = uilabel(app.UIFigure);
            app.LabelV1max.Tag = 'LabelV1max';
            app.LabelV1max.Text = 'V1max,уз';
            app.LabelV2max = uilabel(app.UIFigure);
            app.LabelV2max.Tag = 'LabelV2max';
            app.LabelV2max.Text = 'V2max, уз';
            app.LabelV = uilabel(app.UIFigure);
            app.LabelV.Tag = 'LabelV';
            app.LabelV.Text = 'Максимальная скорость, уз';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Tag = 'Label_4';
            app.Label_4.Text = 'Параметры моделирования разностным методом';
            app.Label_dt = uilabel(app.UIFigure);
            app.Label_dt.Tag = 'Label_dt';
            app.Label_dt.Text = 'Шаг моделирования, с';
            app.Label_x0 = uilabel(app.UIFigure);
            app.Label_x0.Tag = 'Label_x0';
            app.Label_x0.Text = 'Начальное положение, м';
            app.Label_V0 = uilabel(app.UIFigure);
            app.Label_V0.Tag = 'Label_V0';
            app.Label_V0.Text = 'Начальная скорость, м/с';
            app.Label_P0 = uilabel(app.UIFigure);
            app.Label_P0.Tag = 'Label_P0';
            app.Label_P0.Text = 'Начальная мощность, %';
            app.EditField_dt = uieditfield(app.UIFigure, 'numeric');
            app.EditField_dt.ValueChangedFcn = createCallbackFcn(app, @dtValueChanged, true);
            app.EditField_dt.Tag = 'EditField_dt';
            app.EditField_x0 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_x0.ValueChangedFcn = createCallbackFcn(app, @x0ValueChanged, true);
            app.EditField_x0.Tag = 'EditField_x0';
            app.EditField_V0 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_V0.ValueChangedFcn = createCallbackFcn(app, @V0ValueChanged, true);
            app.EditField_V0.Tag = 'EditField_V0';
            app.EditField_P0 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_P0.ValueChangedFcn = createCallbackFcn(app, @P0ValueChanged, true);
            app.EditField_P0.Tag = 'EditField_P0';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Тяга, %';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Step = 10;
            app.Spinner.Limits = [0 100];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @PmaxValueChanged, true);
            app.Spinner.Tag = 'Spinner';
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @mode, true);
            app.ButtonGroup_2.Title = 'Режим работы';
            app.ButtonGroup_2.Tag = 'ButtonGroup_2';
            app.Button_Racing = uiradiobutton(app.ButtonGroup_2);
            app.Button_Racing.Tag = 'Button_Racing';
            app.Button_Racing.Text = 'Разгон';
            app.Button_Braking = uiradiobutton(app.ButtonGroup_2);
            app.Button_Braking.Tag = 'Button_Braking';
            app.Button_Braking.Text = 'Торможение';
            app.Button_Reg = uiradiobutton(app.ButtonGroup_2);
            app.Button_Reg.Tag = 'Button_Reg';
            app.Button_Reg.Text = 'С регулятором';
            app.Button_Full = uiradiobutton(app.ButtonGroup_2);
            app.Button_Full.Tag = 'Button_Full';
            app.Button_Full.Text = 'Разгон+Торможение';
            app.Button_Full.Value = true;
            app.Label_t = uilabel(app.UIFigure);
            app.Label_t.Tag = 'Label_t';
            app.Label_t.Text = 'Время моделирования, с';
            app.Label_Vz = uilabel(app.UIFigure);
            app.Label_Vz.Tag = 'Label_Vz';
            app.Label_Vz.Text = 'Заданная скорость, м/с';
            app.EditField_t = uieditfield(app.UIFigure, 'numeric');
            app.EditField_t.ValueChangedFcn = createCallbackFcn(app, @tValueChanged, true);
            app.EditField_t.Tag = 'EditField_t';
            app.EditField_Vz = uieditfield(app.UIFigure, 'numeric');
            app.EditField_Vz.ValueChangedFcn = createCallbackFcn(app, @VzValueChanged, true);
            app.EditField_Vz.Tag = 'EditField_Vz';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @Start, true);
            app.StartButton.Tag = 'StartButton';
            app.StartButton.Text = 'Start';
            app.Label_kp = uilabel(app.UIFigure);
            app.Label_kp.Text = 'Kp';
            app.Label_kd = uilabel(app.UIFigure);
            app.Label_kd.Text = 'Kd';
            app.EditField_kp = uieditfield(app.UIFigure, 'numeric');
            app.EditField_kd = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end