classdef modal_Fad_Lev < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Label_10         matlab.ui.control.Label
        PushOut          matlab.ui.control.Button
        Panel_result     matlab.ui.container.Panel
        Label_11         matlab.ui.control.Label
        lbRoots          matlab.ui.control.ListBox
        CoefNorm         matlab.ui.control.EditField
        CoefModal        matlab.ui.control.ListBox
        Label_6          matlab.ui.control.Label
        Label_5          matlab.ui.control.Label
        Panel_ish_dan    matlab.ui.container.Panel
        EditField_3      matlab.ui.control.EditField
        EditField_2      matlab.ui.control.EditField
        EditField        matlab.ui.control.EditField
        ePricision       matlab.ui.control.DropDown
        Label_9          matlab.ui.control.Label
        DiscreteTime     matlab.ui.control.NumericEditField
        Label_8          matlab.ui.control.Label
        Outputs          matlab.ui.control.NumericEditField
        UITableC         matlab.ui.control.Table
        UITableB         matlab.ui.control.Table
        UITableA         matlab.ui.control.Table
        CalcButton_disc  matlab.ui.control.Button
        disc_sys         matlab.ui.control.Button
        nepr_sys         matlab.ui.control.Button
        CalcButton       matlab.ui.control.Button
        cbType           matlab.ui.control.DropDown
        Label_4          matlab.ui.control.Label
        Label_3          matlab.ui.control.Label
        eTime            matlab.ui.control.NumericEditField
        Label_2          matlab.ui.control.Label
        CLabel           matlab.ui.control.Label
        BLabel           matlab.ui.control.Label
        ALabel           matlab.ui.control.Label
        Label            matlab.ui.control.Label
        eRank            matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function [roots_req, b] = GetPolyRoots(~,n,t_req,acc_req,poly_type)
            switch(poly_type)
                case 1 % Полином Ньютона
                    z = [];
                    k = 1;
                    p = ones(1,n)*(-1);
                case 2 % Полином Баттерворта
                    [z,p,k]=buttap(n);
                case 3 % Полином Бесселя
                    [z,p,k]=besselap(n);
                otherwise
                    error('Неверно задан тип полинома');
            end
            [b,a]=zp2tf(z,p,k);
            SYS=tf(b,a);
            [Y,T]=step(SYS,0:0.01:30);
            T_dyn = T(Y>1+acc_req | Y < 1-acc_req );
            t_end = T_dyn(end);
            w0 = t_end/t_req;
            for i=1:n     % Рассчёт коэффициентов желаемого полинома
                a(i+1)=a(i+1).*w0^(i);	
            end
            b=a(n+1);  % Расчёт коэффициента числителя  ПФ
            if (poly_type == 1)
                roots_req(1:n) = - w0;
                roots_req = roots_req';
            else
                roots_req = roots(a); 
            end
        end
        
        function [L,E] = fad_lev_Q(~,A,B,pdm,n)
            I = eye(n);
            [R{1:n}] = deal(A);
            [Q{1:n}] = deal(I);
            f = ones(1,n+1);
            for k = 2:n+1
                f(k) = -trace(R{k-1})/(k-1);
                Q{k} = (R{k-1}+f(k)*I);
                R{k} = A*Q{k};
            end
            L = [];
            E = [];
            for j = 2:n+1
                L = [L Q{1,j-1}*B];
                E =[E pdm(j)-f(j)];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PushOut
        function PushOutButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: eRank
        function eRankValueChanged(app, event)
            try
                n = app.eRank.Value;
                if (n <= 0)
                    error('Порядок системы должен быть больше нуля');
                end
                if (round(n)~= n)
                    error('Порядок системы должен быть целым числом');
                end
            catch
                errordlg(lasterr);
            end
            Out = app.Outputs.Value;
            In = 1;
            app.UITableA.Data = zeros(n, n);
            app.UITableB.Data = zeros(n,In);
            app.UITableC.Data = zeros(Out,n);
            
            RowNameA = cell(1, size(app.UITableA.Data, 1));
            for i = 1 : size(app.UITableA.Data, 1)
                RowNameA{1, i}= i;
            end
            app.UITableA.RowName = RowNameA;
            ColumnNameB = cell(1, size(app.UITableA.Data, 2));
            for i = 1 : size(app.UITableA.Data, 2)
                ColumnNameB{i, 1}= i;
            end
            app.UITableA.ColumnName = ColumnNameB;
            
            RowNameB = cell(1, size(app.UITableB.Data, 1));
            for i = 1 : size(app.UITableB.Data, 1)
                RowNameB{1, i}= i;
            end
            app.UITableB.RowName = RowNameB;
            ColumnNameB = cell(1, size(app.UITableB.Data, 2));
            for i = 1 : size(app.UITableB.Data, 2)
                ColumnNameB{i, 1}= i;
            end
            app.UITableB.ColumnName = ColumnNameB;
        end

        % Value changed function: Outputs
        function OutputsValueChanged(app, event)
            try
                Out = app.Outputs.Value;
                if (Out <= 0)
                    error('Количество выходов системы должно быть больше нуля');
                end
                if (round(Out)~= Out)
                    error('Количество выходов системы должно быть целым числом');
                end
            catch
                errordlg(lasterr);
           end
            n = app.eRank.Value;
            In = 1;
            app.UITableC.Data = zeros(Out,n);
            RowNameC = cell(1, size(app.UITableC.Data, 1));
            for i = 1 : size(app.UITableC.Data, 1)
                RowNameC{1, i}= i;
             end
            app.UITableC.RowName = RowNameC;
            ColumnNameC = cell(1, size(app.UITableC.Data, 2));
            for i = 1 : size(app.UITableC.Data, 2)
                ColumnNameC{i, 1}= i;
            end
            app.UITableC.ColumnName = ColumnNameC;
        end

        % Value changed function: eTime
        function eTimeValueChanged(app, event)
            try
                t_req = app.eTime.Value;
                if (t_req <= 0)
                    error('Желаемое время переходного процесса должно быть больше нуля!');
                end
            catch
                errordlg(lasterr);
            end
        end

        % Callback function: CalcButton, UITableC, UITableC, lbRoots
        function CalcButtonPushed(app, event)
            try
                n = app.eRank.Value;
                In = 1;
                Out = app.Outputs.Value;
                t_req = app.eTime.Value;
                if app.ePricision.Value == '1'
                    acc_req = 0.02;
                elseif app.ePricision.Value == '2'
                    acc_req = 0.05;
                end
                if app.cbType.Value == '1'
                    poly_type = '1';
                elseif app.cbType.Value == '2'
                    poly_type = '2';
                elseif app.cbType.Value == '3'
                    poly_type = '3';
                end
                poly_type = str2double(poly_type);
                mtrxA = app.UITableA.Data;
                mtrxB = app.UITableB.Data;
                mtrxC = app.UITableC.Data;
                mtrxD = 0;
                Co = ctrb(mtrxA,mtrxB);
                if (n ~= 0)
                    if (Out ~= 0)
                        if (isequal(mtrxA,zeros(n,n)) == 0)
                            if (isequal(mtrxB,zeros(n,In)) == 0)
                                if (isequal(mtrxC,zeros(Out,n)) == 0)
                                    if (t_req ~= 0)
                                        unctr = length(mtrxA) - rank(Co);
                                        if (unctr == 0)
                                            [roots, ~] = GetPolyRoots(app,n, t_req, acc_req, poly_type);
                                            pdm = poly(roots); % Коэффициенты желаемого характеристического полинома
                                            [L,E] = fad_lev_Q(app,mtrxA,mtrxB,pdm,n);
                                            Koc = E*inv(L); % Коэффициенты обратной связи по алгоритму Фаддеева-Леверье
                                            mtrxAk = mtrxA - mtrxB*Koc; % Матрица A замкнутой системы по алгоритму Фаддеева-Леверье
                                            sysl = ss(mtrxAk,mtrxB,mtrxC,mtrxD); % Описание замкнутой системы
                                            yuk = dcgain(sysl); % Установившееся значение на выходе замкнутой системы
                                            if (Out > 2)
                                                Knorm = 1/yuk(n); % Нормирующий коэффициент для системы
                                            else
                                                Knorm = 1/yuk; % Нормирующий коэффициент для системы
                                            end
                                            Knorm = round(Knorm,4);
                                            app.CoefNorm.Value = num2str(Knorm);
                                            if (size(Koc, 2) ~= 1)
                                                Koc = Koc';
                                            end
                                            Koc = round(Koc,4);
                                            K_Str = strtrim(cellstr(num2str(Koc)));
                                            app.CoefModal.Items = K_Str;
                                            if (size(roots, 2) ~= 1)
                                                roots = roots';
                                            end
                                            roots = round(roots,4);
                                            roots_Str = strtrim(cellstr(num2str(roots)));
                                            app.lbRoots.Items = roots_Str;
                                            disp('---------------------------------------------------');
                                            disp(['Выполнен расчёт непрерывной системы с модальным']);
                                            disp(['управлением со следующими параметрами:']);
                                            disp(['Порядок системы: ',num2str(n)]);
                                            disp(['Количество выходов системы: ',num2str(Out)]);
                                            disp(['Матрица A: ']);
                                            disp(app.UITableA.Data);
                                            disp(['Матрица B: ']);
                                            disp(app.UITableB.Data);
                                            disp(['Матрица C: ']);
                                            disp(app.UITableC.Data);
                                            Type_names = app.cbType.Items;
                                            disp(['Тип характеристического полинома: Стандартный полином ',Type_names{poly_type}]);
                                            disp(['Желаемое время переходного процесса: ',num2str(t_req), ' с']);
                                            disp(['Точность установившегося режима: ',num2str(acc_req*100),'%']);
                                            disp('---------------------------------------------------');
                                            disp('Результаты расчёта:');
                                            disp(['Нормирующий коэффициент системы: ',num2str(Knorm)]);
                                            disp('Корни характеристического полинома системы: ');
                                            disp(roots);
                                            disp('Коэффициенты модального регулятора непрерывной системы: ');
                                            disp(Koc);
                                            disp('---------------------------------------------------');
                                        else
                                            error('Система неуправляема!');
                                        end
                                    else
                                        error('Желаемое время переходного процесса должно быть больше нуля!');
                                    end
                                else
                                    error('Матрица C не может быть полностью нулевой!');
                                end
                            else
                                error('Матрица B не может быть полностью нулевой!');
                            end
                        else
                            error('Матрица A не может быть полностью нулевой!');
                        end
                    else
                        error('Значение выхода должно быть больше нуля!');
                    end
                else
                    error('Значение входа должно быть больше нуля!');
                end
            catch
                errordlg(lasterr);
            end       
        end

        % Button pushed function: nepr_sys
        function nepr_sysButtonPushed(app, event)
            try
                n = app.eRank.Value;
                In = 1;
                Out = app.Outputs.Value;
                t_req = app.eTime.Value;
                if app.ePricision.Value == '1'
                    acc_req = 0.02;
                elseif app.ePricision.Value == '2'
                    acc_req = 0.05;
                end
                if app.cbType.Value == '1'
                    poly_type = '1';
                elseif app.cbType.Value == '2'
                    poly_type = '2';
                elseif app.cbType.Value == '3'
                    poly_type = '3';
                end
                poly_type = str2double(poly_type);
                mtrxA = app.UITableA.Data;
                mtrxB = app.UITableB.Data;
                mtrxC = app.UITableC.Data;
                mtrxD = 0;
                Co = ctrb(mtrxA,mtrxB);
                if (n ~= 0)
                    if (Out ~= 0)
                        if (isequal(mtrxA,zeros(n,n)) == 0)
                            if (isequal(mtrxB,zeros(n,In)) == 0)
                                if (isequal(mtrxC,zeros(Out,n)) == 0)
                                    if (t_req ~= 0)
                                        unctr = length(mtrxA) - rank(Co);
                                        if (unctr == 0)
                                            assignin("base", 'mtrxA', mtrxA);
                                            assignin("base", 'mtrxB', mtrxB);
                                            assignin("base", 'mtrxC', mtrxC);
                                            [roots, ~] = GetPolyRoots(app,n, t_req, acc_req, poly_type);
                                            pdm = poly(roots); % Коэффициенты желаемого хорактеристического полинома
                                            [L,E] = fad_lev_Q(app,mtrxA,mtrxB,pdm,n);
                                            Koc = E*inv(L); % Коэффициенты обратной связи по алгоритму Фаддеева-Леверье
                                            mtrxAk = mtrxA - mtrxB*Koc; % Матрица A замкнутой системы по алгоритму Фаддеева-Леверье
                                            sysl = ss(mtrxAk,mtrxB,mtrxC,mtrxD); % Описание замкнутой системы
                                            yuk = dcgain(sysl); % Установившееся значение на выходе замкнутой системы
                                            if (Out > 2)
                                                Knorm = 1/yuk(n); % Нормирующий коэффициент для системы
                                            else
                                                Knorm = 1/yuk; % Нормирующий коэффициент для системы
                                            end
                                            assignin("base", 'Kmodal', Koc);
                                            assignin("base", 'Knorm', Knorm);
                                            assignin("base", 'StopTime', t_req*2.5);
                                            assignin("base", 'Out', Out);
                                            new_system('model_continuous');
                                            set_param('model_continuous', 'StopTime', 'StopTime');
                                            add_block('simulink/Sources/Step','model_continuous/Step');
                                            set_param('model_continuous/Step', 'Position', [140, 80, 180, 120], 'Time', '0');
                                            add_block('simulink/Math Operations/Gain','model_continuous/Knorm');
                                            set_param('model_continuous/Knorm', 'Position', [220, 80, 280, 120], 'Gain', 'Knorm');
                                            add_line('model_continuous', 'Step/1', 'Knorm/1');
                                            add_block('simulink/Math Operations/Sum','model_continuous/Sum');
                                            set_param('model_continuous/Sum', 'Position', [320, 80, 360, 120], 'Inputs', '|+-');
                                            add_line('model_continuous', 'Knorm/1', 'Sum/1', 'autorouting', 'on');
                                            add_block('simulink/Math Operations/Gain','model_continuous/matrix_B');
                                            set_param('model_continuous/matrix_B', 'Position', [400, 80, 460, 120], 'Multiplication', 'Matrix(K*u)', 'Gain', 'mtrxB');
                                            add_line('model_continuous', 'Sum/1', 'matrix_B/1');
                                            add_block('simulink/Math Operations/Sum','model_continuous/Sum1');
                                            set_param('model_continuous/Sum1', 'Position', [500, 80, 540, 120], 'Inputs', '|++');
                                            add_line('model_continuous', 'matrix_B/1', 'Sum1/1', 'autorouting', 'on');
                                            add_block('simulink/Continuous/Integrator','model_continuous/Integrator');
                                            set_param('model_continuous/Integrator', 'Position', [580, 80, 620, 120]);
                                            add_line('model_continuous', 'Sum1/1', 'Integrator/1');
                                            add_block('simulink/Math Operations/Gain','model_continuous/matrix_A');
                                            set_param('model_continuous/matrix_A', 'Position', [540, 160, 600, 200], 'Orientation', 'left', 'Multiplication', 'Matrix(K*u) (u vector)', 'Gain', 'mtrxA');
                                            add_line('model_continuous', 'Integrator/1', 'matrix_A/1', 'autorouting', 'on');
                                            add_line('model_continuous', 'matrix_A/1', 'Sum1/2', 'autorouting', 'on');
                                            add_block('simulink/Math Operations/Gain','model_continuous/matrix_C');
                                            set_param('model_continuous/matrix_C', 'Position', [660, 80, 720, 120], 'Multiplication', 'Matrix(K*u) (u vector)', 'Gain', 'mtrxC(Out,:)');
                                            add_line('model_continuous', 'Integrator/1', 'matrix_C/1');
                                            add_block('simulink/Math Operations/Gain','model_continuous/Kmodal');
                                            set_param('model_continuous/Kmodal', 'Position', [400, 220, 460, 260], 'Orientation', 'left', 'Multiplication', 'Matrix(K*u) (u vector)', 'Gain', 'Kmodal');
                                            add_line('model_continuous', 'Integrator/1', 'Kmodal/1', 'autorouting', 'on');
                                            add_line('model_continuous', 'Kmodal/1', 'Sum/2', 'autorouting', 'on');
                                            add_block('simulink/Sinks/Scope','model_continuous/Scope');
                                            set_param('model_continuous/Scope', 'Position', [760, 60, 810, 140],'Title', 'Выход непрерывной системы','SampleTime','StopTime/500');
                                            set_param('model_continuous/Scope','DataLogging','on','DataLoggingVariableName','out_continuous','DataLoggingSaveFormat','Array');
                                            add_line('model_continuous', 'matrix_C/1', 'Scope/1', 'autorouting', 'on');
                                            add_block('simulink/Sinks/Scope','model_continuous/Scope1');
                                            set_param('model_continuous/Scope1', 'Position', [760, -40, 810, 40],'Title', 'Переменные состояния непрерывной системы','SampleTime','StopTime/500');
                                            add_line('model_continuous',[635 100; 635 0; 635 0; 755 0]);
                                            Scope1Configuration = get_param('model_continuous/Scope1','ScopeConfiguration');
                                            Scope1Configuration.ShowLegend = true;
                                            a1 = Simulink.Annotation(gcs,'v(t)');
                                            a1.Position = [290 80 310 120];
                                            a1.FontSize = 14;
                                            a2 = Simulink.Annotation(gcs,'u(t)');
                                            a2.Position = [365 80 390 120];
                                            a2.FontSize = 14;
                                            a3 = Simulink.Annotation(gcs,'x\prime(t)');
                                            a3.Position = [545 80 570 120];
                                            a3.FontSize = 14;
                                            a3.Interpreter = 'tex';
                                            a4 = Simulink.Annotation(gcs,'x(t)');
                                            a4.Position = [605 0 630 60];
                                            a4.FontSize = 14;
                                            a5 = Simulink.Annotation(gcs,'Yn(t)');
                                            a5.Position = [715 80 740 120];
                                            a5.FontSize = 14;
                                            a6 = Simulink.Annotation(gcs,'g(t)');
                                            a6.Position = [190 80 310 120];
                                            a6.FontSize = 14;
                                            open_system('model_continuous')
                                        else
                                            error('Система неуправляема!');
                                        end
                                    else
                                        error('Желаемое время переходного процесса должно быть больше нуля!');
                                    end
                                else
                                    error('Матрица C не может быть полностью нулевой!');
                                end
                            else
                                error('Матрица B не может быть полностью нулевой!');
                            end
                        else
                            error('Матрица A не может быть полностью нулевой!');
                        end
                    else
                        error('Значение выхода должно быть больше нуля!');
                    end
                else
                    error('Значение входа должно быть больше нуля!');
                end
            catch
                errordlg(lasterr);
            end
        end

        % Value changed function: DiscreteTime
        function DiscreteTimeValueChanged(app, event)
            try
                T = app.DiscreteTime.Value;
                if (T <= 0)
                    error('Период дискретизации должен быть больше нуля');
                end
            catch
                errordlg(lasterr);
            end
        end

        % Button pushed function: CalcButton_disc
        function CalcButton_discPushed(app, event)
            try
                n = app.eRank.Value;
                In = 1;
                Out = app.Outputs.Value;
                t_req = app.eTime.Value;
                if app.ePricision.Value == '1'
                    acc_req = 0.02;
                elseif app.ePricision.Value == '2'
                    acc_req = 0.05;
                end
                if app.cbType.Value == '1'
                    poly_type = '1';
                elseif app.cbType.Value == '2'
                    poly_type = '2';
                elseif app.cbType.Value == '3'
                    poly_type = '3';
                end
                poly_type = str2double(poly_type);
                mtrxA = app.UITableA.Data;
                mtrxB = app.UITableB.Data;
                mtrxC = app.UITableC.Data;
                mtrxD = 0;
                T = app.DiscreteTime.Value;
                Co = ctrb(mtrxA,mtrxB);
                if (n ~= 0)
                    if (Out ~= 0)
                        if (isequal(mtrxA,zeros(n,n)) == 0)
                            if (isequal(mtrxB,zeros(n,In)) == 0)
                                if (isequal(mtrxC,zeros(Out,n)) == 0)
                                    if (t_req ~= 0)
                                        if (T ~= 0)
                                            unctr = length(mtrxA) - rank(Co);
                                            if (unctr == 0)
                                                [roots, ~] = GetPolyRoots(app,n, t_req, acc_req, poly_type);
                                                disc_roots = exp(roots*T);
                                                sys = ss(mtrxA, mtrxB, mtrxC, mtrxD);
                                                sys_disc = c2d(sys, T);
                                                [Ad, Bd, Cd, Dd] = ssdata(sys_disc);
                                                pdm = poly(disc_roots);
                                                [L,E] = fad_lev_Q(app,Ad,Bd,pdm,n);
                                                Kdoc = E*inv(L);
                                                Adk = Ad - Bd*Kdoc;
                                                sysdz = ss(Adk,Bd,Cd,Dd,T);
                                                yduk = dcgain(sysdz);
                                                if (Out > 2)
                                                    Knorm_disc = 1/yduk(n); % Нормирующий коэффициент для системы
                                                else
                                                    Knorm_disc = 1/yduk; % Нормирующий коэффициент для системы
                                                end
                                                Knorm_disc = round(Knorm_disc,4);
                                                app.CoefNorm.Value = num2str(Knorm_disc);
                                                if (size(Kdoc, 2) ~= 1)
                                                    Kdoc = Kdoc';
                                                end
                                                Kdoc = round(Kdoc,4);
                                                K_Str_disc = strtrim(cellstr(num2str(Kdoc)));
                                                app.CoefModal.Items = K_Str_disc;
                                                if (size(disc_roots, 2) ~= 1)
                                                    disc_roots = disc_roots';
                                                end
                                                disc_roots = round(disc_roots,4);
                                                roots_Str = strtrim(cellstr(num2str(disc_roots)));
                                                app.lbRoots.Items = roots_Str;
                                                disp('---------------------------------------------------');
                                                disp(['Выполнен расчёт эквивалентной дискретной системы']);
                                                disp(['с модальным управлением со следующими параметрами:']);
                                                disp(['Порядок системы: ',num2str(n)]);
                                                disp(['Количество выходов системы: ',num2str(Out)]);
                                                disp(['Матрица Ad: ']);
                                                disp(Ad);
                                                disp(['Матрица Bd: ']);
                                                disp(Bd);
                                                disp(['Матрица Cd: ']);
                                                disp(Cd);
                                                Type_names = app.cbType.Items;
                                                disp(['Тип полинома непрерывной системы-прототипа:']);
                                                disp(['Стандартный полином ',Type_names{poly_type}]);
                                                disp(['Желаемое время переходного процесса: ',num2str(t_req), ' с']);
                                                disp(['Точность установившегося режима: ',num2str(acc_req*100),'%']);
                                                disp(['Период дискретизации: ',num2str(T), ' с']);
                                                disp('---------------------------------------------------');
                                                disp('Результаты расчёта:');
                                                disp(['Нормирующий коэффициент дискретной системы: ',num2str(Knorm_disc)]);
                                                disp(['Корни характеристического полинома системы в Z-области: ']);
                                                disp(disc_roots);
                                                disp('Коэффициенты модального регулятора дискретной системы: ');
                                                disp(Kdoc);
                                                disp('---------------------------------------------------');
                                            else
                                                error('Система неуправляема!');
                                            end
                                        else
                                            error('Период дискретизации должен быть больше нуля!');
                                        end
                                    else
                                        error('Желаемое время переходного процесса должно быть больше нуля!');
                                    end
                                else
                                    error('Матрица C не может быть полностью нулевой!');
                                end
                            else
                                error('Матрица B не может быть полностью нулевой!');
                            end
                        else
                            error('Матрица A не может быть полностью нулевой!');
                        end
                    else
                        error('Значение выхода должно быть больше нуля!');
                    end
                else
                    error('Значение входа должно быть больше нуля!');
                end
            catch
                errordlg(lasterr);
            end  
        end

        % Button pushed function: disc_sys
        function disc_sysButtonPushed(app, event)
            try
                n = app.eRank.Value;
                In = 1;
                Out = app.Outputs.Value;
                t_req = app.eTime.Value;
                if app.ePricision.Value == '1'
                    acc_req = 0.02;
                elseif app.ePricision.Value == '2'
                    acc_req = 0.05;
                end
                if app.cbType.Value == '1'
                    poly_type = '1';
                elseif app.cbType.Value == '2'
                    poly_type = '2';
                elseif app.cbType.Value == '3'
                    poly_type = '3';
                end
                poly_type = str2double(poly_type);
                mtrxA = app.UITableA.Data;
                mtrxB = app.UITableB.Data;
                mtrxC = app.UITableC.Data;
                mtrxD = 0;
                T = app.DiscreteTime.Value;
                Co = ctrb(mtrxA,mtrxB);
                if (n ~= 0)
                    if (Out ~= 0)
                        if (isequal(mtrxA,zeros(n,n)) == 0)
                            if (isequal(mtrxB,zeros(n,In)) == 0)
                                if (isequal(mtrxC,zeros(Out,n)) == 0)
                                    if (t_req ~= 0)
                                        if (T ~= 0)
                                            unctr = length(mtrxA) - rank(Co);
                                            if (unctr == 0)
                                                [roots, ~] = GetPolyRoots(app,n, t_req, acc_req, poly_type);
                                                assignin("base", 'T', T);
                                                disc_roots = exp(roots*T);
                                                sys = ss(mtrxA, mtrxB, mtrxC, mtrxD);
                                                sys_disc = c2d(sys, T);
                                                [Ad, Bd, Cd, Dd] = ssdata(sys_disc);
                                                pdm = poly(disc_roots);
                                                [L,E] = fad_lev_Q(app,Ad,Bd,pdm,n);
                                                Kdoc = E*inv(L);
                                                Adk = Ad - Bd*Kdoc;
                                                sysdz = ss(Adk,Bd,Cd,Dd,T);
                                                yduk = dcgain(sysdz);
                                                if (Out > 2)
                                                    Knorm_disc = 1/yduk(n); % Нормирующий коэффициент для системы
                                                else
                                                    Knorm_disc = 1/yduk; % Нормирующий коэффициент для системы
                                                end
                                                assignin("base", 'K_disc', Kdoc);
                                                assignin("base", 'Knorm_disc', Knorm_disc);
                                                assignin("base", 'mtrxA_disc', Ad);
                                                assignin("base", 'mtrxB_disc', Bd);
                                                assignin("base", 'mtrxC_disc', Cd);
                                                assignin("base", 'StopTime', t_req*2.5);
                                                assignin("base", 'Out', Out);
                                                new_system('model_discrete');
                                                set_param('model_discrete', 'SolverType', 'Fixed-step', 'FixedStep', 'T', 'StopTime', 'StopTime', 'EnableMultiTasking', 'on', 'ReturnWorkspaceOutputs', 'off', 'PropagateSignalLabelsOutOfModel', 'off');
                                                add_block('simulink/Sources/Step','model_discrete/Step');
                                                set_param('model_discrete/Step', 'Position', [140, 80, 180, 120], 'Time', '0');
                                                add_block('simulink/Math Operations/Gain','model_discrete/Knorm');
                                                set_param('model_discrete/Knorm', 'Position', [220, 80, 280, 120], 'Gain', 'Knorm_disc');
                                                add_line('model_discrete', 'Step/1', 'Knorm/1');
                                                add_block('simulink/Math Operations/Sum','model_discrete/Sum');
                                                set_param('model_discrete/Sum', 'Position', [320, 80, 360, 120], 'Inputs', '|+-');
                                                add_line('model_discrete', 'Knorm/1', 'Sum/1', 'autorouting', 'on');
                                                add_block('simulink/Math Operations/Gain','model_discrete/matrix_B');
                                                set_param('model_discrete/matrix_B', 'Position', [400, 80, 460, 120], 'Multiplication', 'Matrix(K*u)', 'Gain', 'mtrxB_disc');
                                                add_line('model_discrete', 'Sum/1', 'matrix_B/1');
                                                add_block('simulink/Math Operations/Sum','model_discrete/Sum1');
                                                set_param('model_discrete/Sum1', 'Position', [480, 80, 520, 120]);
                                                add_line('model_discrete', 'matrix_B/1', 'Sum1/1', 'autorouting', 'on');
                                                add_block('simulink/Discrete/Unit Delay','model_discrete/Unit Delay');
                                                set_param('model_discrete/Unit Delay', 'Position', [580, 80, 620, 120], 'SampleTime', 'T');
                                                add_line('model_discrete', 'Sum1/1', 'Unit Delay/1');
                                                add_block('simulink/Math Operations/Gain','model_discrete/matrix_A');
                                                set_param('model_discrete/matrix_A', 'Position', [540, 160, 600, 200], 'Orientation', 'left', 'Multiplication', 'Matrix(K*u) (u vector)', 'Gain', 'mtrxA_disc');
                                                add_line('model_discrete', 'Unit Delay/1', 'matrix_A/1', 'autorouting', 'on');
                                                add_line('model_discrete', 'matrix_A/1', 'Sum1/2', 'autorouting', 'on');
                                                add_block('simulink/Math Operations/Gain','model_discrete/matrix_C');
                                                set_param('model_discrete/matrix_C', 'Position', [660, 80, 720, 120], 'Multiplication', 'Matrix(K*u) (u vector)', 'Gain', 'mtrxC_disc(Out,:)');
                                                add_line('model_discrete', 'Unit Delay/1', 'matrix_C/1');
                                                add_block('simulink/Discrete/Zero-Order Hold','model_discrete/Zero-Order Hold');
                                                set_param('model_discrete/Zero-Order Hold', 'Position', [500, 220, 540, 260], 'Orientation', 'left', 'SampleTime', 'T');
                                                add_line('model_discrete', 'Unit Delay/1', 'Zero-Order Hold/1', 'autorouting', 'on');
                                                add_block('simulink/Math Operations/Gain','model_discrete/Kmodal');
                                                set_param('model_discrete/Kmodal', 'Position', [400, 220, 460, 260], 'Orientation', 'left', 'Multiplication', 'Matrix(K*u) (u vector)', 'Gain', 'K_disc');
                                                add_line('model_discrete', 'Zero-Order Hold/1', 'Kmodal/1', 'autorouting', 'on');
                                                add_line('model_discrete', 'Kmodal/1', 'Sum/2', 'autorouting', 'on');
                                                add_block('simulink/Sinks/Scope','model_discrete/Scope');
                                                set_param('model_discrete/Scope', 'Position', [760, 60, 810, 140],'Title', 'Выход дискретной системы' );
                                                set_param('model_discrete/Scope','DataLogging','on','DataLoggingVariableName','out_discrete','DataLoggingSaveFormat','Array');
                                                add_line('model_discrete', 'matrix_C/1', 'Scope/1', 'autorouting', 'on');
                                                add_block('simulink/Sinks/Scope','model_discrete/Scope1');
                                                set_param('model_discrete/Scope1', 'Position', [760, -40, 810, 40],'Title', 'Переменные состояния дискретной системы' );
                                                add_line('model_discrete',[635 100; 635 0; 635 0; 755 0]);
                                                myScopeConfiguration = get_param('model_discrete/Scope1','ScopeConfiguration');
                                                myScopeConfiguration.ShowLegend = true;
                                                a1 = Simulink.Annotation(gcs,'v(k)');
                                                a1.Position = [285 80 310 120];
                                                a1.FontSize = 14;
                                                a2 = Simulink.Annotation(gcs,'u(k)');
                                                a2.Position = [365 80 390 120];
                                                a2.FontSize = 14;
                                                a3 = Simulink.Annotation(gcs,'x(k+1)');
                                                a3.Position = [530 80 570 120];
                                                a3.FontSize = 14;
                                                a3.Interpreter = 'tex';
                                                a4 = Simulink.Annotation(gcs,'x(k)');
                                                a4.Position = [605 0 630 60];
                                                a4.FontSize = 14;
                                                a5 = Simulink.Annotation(gcs,'Yn(k)');
                                                a5.Position = [715 80 740 120];
                                                a5.FontSize = 14;
                                                a6 = Simulink.Annotation(gcs,'g(k)');
                                                a6.Position = [185 80 310 120];
                                                a6.FontSize = 14;
                                                open_system('model_discrete')
                                            else
                                                error('Система неуправляема!');
                                            end
                                        else
                                            error('Период дискретизации должен быть больше нуля!');
                                        end
                                    else
                                        error('Желаемое время переходного процесса должно быть больше нуля!');
                                    end
                                else
                                    error('Матрица C не может быть полностью нулевой!');
                                end
                            else
                                error('Матрица B не может быть полностью нулевой!');
                            end
                        else
                            error('Матрица A не может быть полностью нулевой!');
                        end
                    else
                        error('Значение выхода должно быть больше нуля!');
                    end
                else
                    error('Значение входа должно быть больше нуля!');
                end
            catch
                errordlg(lasterr);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_ish_dan = uipanel(app.UIFigure);
            app.Panel_ish_dan.Title = 'Исходные данные непрерывной системы';
            app.Panel_ish_dan.Tag = 'Panel_ish_dan';
            app.eRank = uieditfield(app.Panel_ish_dan, 'numeric');
            app.eRank.ValueChangedFcn = createCallbackFcn(app, @eRankValueChanged, true);
            app.eRank.Tag = 'eRank';
            app.Label = uilabel(app.Panel_ish_dan);
            app.Label.Text = 'Порядок системы';
            app.ALabel = uilabel(app.Panel_ish_dan);
            app.ALabel.Text = 'Матрица A';
            app.BLabel = uilabel(app.Panel_ish_dan);
            app.BLabel.Text = 'Матрица B';
            app.CLabel = uilabel(app.Panel_ish_dan);
            app.CLabel.Text = 'Матрица C';
            app.Label_2 = uilabel(app.Panel_ish_dan);
            app.Label_2.Text = 'Желаемое время переходного процесса, с';
            app.eTime = uieditfield(app.Panel_ish_dan, 'numeric');
            app.eTime.ValueChangedFcn = createCallbackFcn(app, @eTimeValueChanged, true);
            app.eTime.Tag = 'eTime';
            app.Label_3 = uilabel(app.Panel_ish_dan);
            app.Label_3.Text = 'Точность установившегося режима, %';
            app.Label_4 = uilabel(app.Panel_ish_dan);
            app.Label_4.Text = 'Тип желаемого стандартного полинома';
            app.cbType = uidropdown(app.Panel_ish_dan);
            app.cbType.Items = {'Ньютона', 'Баттерворта', 'Бесселя'};
            app.cbType.ItemsData = {'1', '2', '3'};
            app.cbType.Tag = 'cbType';
            app.cbType.Value = '1';
            app.CalcButton = uibutton(app.Panel_ish_dan, 'push');
            app.CalcButton.ButtonPushedFcn = createCallbackFcn(app, @CalcButtonPushed, true);
            app.CalcButton.Tag = 'CalcButton';
            app.CalcButton.Text = {'Рассчитать'; 'коэф-ты непрерывного'; 'модального регулятора'};
            app.nepr_sys = uibutton(app.Panel_ish_dan, 'push');
            app.nepr_sys.ButtonPushedFcn = createCallbackFcn(app, @nepr_sysButtonPushed, true);
            app.nepr_sys.Tag = 'nepr_sys';
            app.nepr_sys.Text = {'Построить непрерывную'; 'систему'; 'с  модальным регулятором'};
            app.disc_sys = uibutton(app.Panel_ish_dan, 'push');
            app.disc_sys.ButtonPushedFcn = createCallbackFcn(app, @disc_sysButtonPushed, true);
            app.disc_sys.Tag = 'disc_sys';
            app.disc_sys.Text = {'Построить дискретную'; ' систему'; 'с модальным регулятором'};
            app.CalcButton_disc = uibutton(app.Panel_ish_dan, 'push');
            app.CalcButton_disc.ButtonPushedFcn = createCallbackFcn(app, @CalcButton_discPushed, true);
            app.CalcButton_disc.Tag = 'CalcButton_disc';
            app.CalcButton_disc.Text = {'Рассчитать'; 'коэф-ты дискретного'; 'модального регулятора'};
            app.UITableA = uitable(app.Panel_ish_dan);
            app.UITableA.ColumnName = '';
            app.UITableA.RowName = {};
            app.UITableA.ColumnEditable = true;
            app.UITableA.Tag = 'UITableA';
            app.UITableB = uitable(app.Panel_ish_dan);
            app.UITableB.ColumnName = '';
            app.UITableB.RowName = {};
            app.UITableB.ColumnEditable = true;
            app.UITableB.Tag = 'UITableB';
            app.UITableC = uitable(app.Panel_ish_dan);
            app.UITableC.ColumnName = '';
            app.UITableC.RowName = {};
            app.UITableC.ColumnEditable = true;
            app.UITableC.CellEditCallback = createCallbackFcn(app, @CalcButtonPushed, true);
            app.UITableC.CellSelectionCallback = createCallbackFcn(app, @CalcButtonPushed, true);
            app.UITableC.Tag = 'UITableC';
            app.Outputs = uieditfield(app.Panel_ish_dan, 'numeric');
            app.Outputs.Limits = [0 20];
            app.Outputs.ValueChangedFcn = createCallbackFcn(app, @OutputsValueChanged, true);
            app.Outputs.Tag = 'Outputs';
            app.Label_8 = uilabel(app.Panel_ish_dan);
            app.Label_8.Text = {'Количество выходов'; 'системы'};
            app.DiscreteTime = uieditfield(app.Panel_ish_dan, 'numeric');
            app.DiscreteTime.ValueChangedFcn = createCallbackFcn(app, @DiscreteTimeValueChanged, true);
            app.DiscreteTime.Tag = 'DiscreteTime';
            app.Label_9 = uilabel(app.Panel_ish_dan);
            app.Label_9.Text = 'Период дискретизации, с';
            app.ePricision = uidropdown(app.Panel_ish_dan);
            app.ePricision.Items = {'2', '5'};
            app.ePricision.ItemsData = {'1', '2'};
            app.ePricision.Tag = 'ePricision';
            app.ePricision.Value = '2';
            app.EditField = uieditfield(app.Panel_ish_dan, 'text');
            app.EditField.Placeholder = 'non';
            app.EditField.Value = 'Эквивалентная дискретная система';
            app.EditField_2 = uieditfield(app.Panel_ish_dan, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = 'Параметры расчета';
            app.EditField_3 = uieditfield(app.Panel_ish_dan, 'text');
            app.EditField_3.Value = 'Непрерывная система';
            app.Panel_result = uipanel(app.UIFigure);
            app.Panel_result.Title = 'Результаты расчёта';
            app.Panel_result.Tag = 'Panel_result';
            app.Label_5 = uilabel(app.Panel_result);
            app.Label_5.Text = 'Нормирующий коэффициент системы';
            app.Label_6 = uilabel(app.Panel_result);
            app.Label_6.Text = 'Коэффициенты модального регулятора';
            app.CoefModal = uilistbox(app.Panel_result);
            app.CoefModal.Items = {};
            app.CoefModal.Tag = 'CoefModal';
            app.CoefModal.Value = {};
            app.CoefNorm = uieditfield(app.Panel_result, 'text');
            app.CoefNorm.Tag = 'CoefNorm';
            app.lbRoots = uilistbox(app.Panel_result);
            app.lbRoots.Items = {};
            app.lbRoots.ValueChangedFcn = createCallbackFcn(app, @CalcButtonPushed, true);
            app.lbRoots.Tag = 'lbRoots';
            app.lbRoots.Value = {};
            app.Label_11 = uilabel(app.Panel_result);
            app.Label_11.Text = {'Корни'; 'характеристического полинома'};
            app.PushOut = uibutton(app.UIFigure, 'push');
            app.PushOut.ButtonPushedFcn = createCallbackFcn(app, @PushOutButtonPushed, true);
            app.PushOut.Tag = 'PushOut';
            app.PushOut.Text = 'Выход';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = 'Синтез систем с одним входом и модальным управлением методом Фаддеева-Леверье';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modal_Fad_Lev

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end