classdef metod_std_polynom_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        mnFile_Exit  matlab.ui.container.Menu
        PushOut      matlab.ui.control.Button
        uipanel3     matlab.ui.container.Panel
        axRoots      matlab.ui.control.UIAxes
        uipanel2     matlab.ui.container.Panel
        lbRoots      matlab.ui.control.ListBox
        text7        matlab.ui.control.Label
        eStaticCoef  matlab.ui.control.EditField
        text6        matlab.ui.control.Label
        uipanel1     matlab.ui.container.Panel
        text8        matlab.ui.control.Label
        cbPlotAll    matlab.ui.control.CheckBox
        btCalc       matlab.ui.control.Button
        text4        matlab.ui.control.Label
        text2        matlab.ui.control.Label
        ePrecision   matlab.ui.control.EditField
        eTime        matlab.ui.control.EditField
        cbType       matlab.ui.control.DropDown
        eRank        matlab.ui.control.EditField
        text1        matlab.ui.control.Label
    end

    
    methods (Access = private)
        function PlotGraphs(app, handles)
            if (handles.calc_on ~= 1)
                return;
            end;
            if (get(handles.cbPlotAll,'Value'))
                Type_names = get(handles.cbType,'String');
                LineWidthes = ones(1,length(Type_names))*2;
                LineWidthes(handles.poly_type) = 3;
                Colors = get(gca,'ColorOrder');
                for i=1:length(Type_names)
                    [roots, coeff]=...
                        GetPolyRoots(handles.n,handles.t_req,handles.acc_req,i);
                    [y,x] = step(zpk([],roots,coeff),handles.t_req*1.5);
                    plot(x,y,'LineWidth',LineWidthes(i),'Color', Colors(i,:));
                    hold on;
                end
                hold off;
                grid on;
                legend(Type_names,'FontName','ZapfDingbats','Location','Best');
            
            else
                Type_names = get(handles.cbType,'String');
                Colors = get(gca,'ColorOrder');
                [y,x] =step(zpk([],handles.roots,handles.b),handles.t_req*1.5);
                plot(x,y,'LineWidth',3,'Color', Colors(handles.poly_type,:));
                grid on
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function metod_std_polynom_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to metod_std_polynom (see VARARGIN)
            
            % Choose default command line output for metod_std_polynom
            handles.output = hObject;
            handles.calc_on = 0;
            % Update handles structure
            guidata(hObject, handles);
        end

        % Callback function: PushOut, eStaticCoef
        function PushOut_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to PushOut (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close('metod_std_polynom');
        end

        % Button pushed function: btCalc
        function btCalc_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btCalc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.calc_on    = 1;
            handles.n          = eval(get(handles.eRank,'String'));
            handles.t_req      = eval(get(handles.eTime,'String'));
            handles.acc_req    = eval(get(handles.ePrecision,'String'));
            handles.poly_type  = get(handles.cbType,'Value');
            [handles.roots, handles.b] =...
                GetPolyRoots(handles.n,handles.t_req,handles.acc_req,handles.poly_type );
            
            set(handles.eStaticCoef,'String',num2str(handles.b));
            if (size(handles.roots,2) ~= 1)
                handles.roots = handles.roots';
            end
            roots_Str = strtrim(cellstr(num2str(handles.roots)));
            set(handles.lbRoots,'String',roots_Str);
            display('---------------------------------------------------');
            display('Выполнен расчет со следующими параметрами:')
            display(['Порядок полинома: ',num2str(handles.n)]);
            Type_names = get(handles.cbType,'String');
            display(['Тип: Cтандартный полином ',Type_names{handles.poly_type}]);
            display(['Желаемое время перехдного процесса: ',num2str(handles.t_req)]);
            display(['Точность установившегося режима: ',num2str(handles.acc_req*100),'%']);
            display('---------------------------------------------------');
            display('Результаты расчета:');
            display(['Статический коэффициент усиления: ', num2str(handles.b)]);
            display('Корни полинома: ');
            disp(roots_Str);
            display('---------------------------------------------------');
            guidata(gcbo,handles)
            PlotGraphs(app, handles)
        end

        % Value changed function: cbPlotAll
        function cbPlotAll_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cbPlotAll (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of cbPlotAll
            PlotGraphs(app, handles)
        end

        % Value changed function: ePrecision
        function ePrecision_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ePrecision (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ePrecision as text
            %        str2double(get(hObject,'String')) returns contents of ePrecision as a double
            try
                acc_req    = eval(get(handles.ePrecision,'String'))*0.01;
                if(~isnumeric(acc_req))
                    error('Точность должна  быть числом!')
                end
                if (acc_req > 10 || acc_req < 0)
                    error('Точность должна лежать в пределах [0;1o]!');
                end;
            catch
                errordlg(lasterr);
                set(handles.eXMax,'String','10');
            end
        end

        % Value changed function: eRank
        function eRank_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to eRank (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of eRank as text
            %        str2double(get(hObject,'String')) returns contents of eRank as a double
            try
                n =  eval(get(handles.eRank,'String'));
                if(~isnumeric(n))
                    error('Ранг системы должен быть числом!')
                end
                if (n <= 0)
                    error('Ранг системы должен быть строго больше нуля');
                end;
                if (round(n)~= n)
                    error('Ранг системы должен быть целым числом');
                end;
            catch
                errordlg(lasterr);
                set(handles.eXMax,'String','0.05');
            end
        end

        % Value changed function: eTime
        function eTime_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to eTime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of eTime as text
            %        str2double(get(hObject,'String')) returns contents of eTime as a double
            try
                t_req      = eval(get(handles.eTime,'String'));
                if(~isnumeric(t_req))
                    error('Желаемое время должно быть числом!')
                end
                if (t_req <= 0)
                    error('Желаемое время должно быть положительным!');
                end;
            catch
                errordlg(lasterr);
                set(handles.eXMax,'String','5');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'metod_std_polynom';
            app.figure1.Tag = 'figure1';
            app.mnFile_Exit = uimenu(app.figure1);
            app.mnFile_Exit.Accelerator = 'E';
            app.mnFile_Exit.Text = 'Вычисление желаемых корней по стандартным полиномам';
            app.mnFile_Exit.Tag = 'mnFile_Exit';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Исходные данные';
            app.uipanel1.Tag = 'uipanel1';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Порядок системы';
            app.eRank = uieditfield(app.uipanel1, 'text');
            app.eRank.ValueChangedFcn = createCallbackFcn(app, @eRank_Callback, true);
            app.eRank.Tag = 'eRank';
            app.cbType = uidropdown(app.uipanel1);
            app.cbType.Items = {'Ньютона', 'Баттерворта', 'Бесселя'};
            app.cbType.Tag = 'cbType';
            app.cbType.Value = 'Ньютона';
            app.eTime = uieditfield(app.uipanel1, 'text');
            app.eTime.ValueChangedFcn = createCallbackFcn(app, @eTime_Callback, true);
            app.eTime.Tag = 'eTime';
            app.ePrecision = uieditfield(app.uipanel1, 'text');
            app.ePrecision.ValueChangedFcn = createCallbackFcn(app, @ePrecision_Callback, true);
            app.ePrecision.Tag = 'ePrecision';
            app.ePrecision.Value = '0.05';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Тип стандартного полинома';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = {'Точность установившегося'; 'режима (0.02, 0.05, ...)'};
            app.btCalc = uibutton(app.uipanel1, 'push');
            app.btCalc.ButtonPushedFcn = createCallbackFcn(app, @btCalc_Callback, true);
            app.btCalc.Tag = 'btCalc';
            app.btCalc.Text = 'Выполнить расчет';
            app.cbPlotAll = uicheckbox(app.uipanel1);
            app.cbPlotAll.ValueChangedFcn = createCallbackFcn(app, @cbPlotAll_Callback, true);
            app.cbPlotAll.Tag = 'cbPlotAll';
            app.cbPlotAll.Text = 'Показать графики полиномов всех типов';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Tag = 'text8';
            app.text8.Text = {'Желаемое время'; 'переходного процесса, с'};
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Результаты расчета';
            app.uipanel2.Tag = 'uipanel2';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Коэффициент числителя ПФ';
            app.eStaticCoef = uieditfield(app.uipanel2, 'text');
            app.eStaticCoef.ValueChangedFcn = createCallbackFcn(app, @PushOut_Callback, true);
            app.eStaticCoef.Tag = 'eStaticCoef';
            app.text7 = uilabel(app.uipanel2);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Корни полинома';
            app.lbRoots = uilistbox(app.uipanel2);
            app.lbRoots.Items = {};
            app.lbRoots.Tag = 'lbRoots';
            app.lbRoots.Value = {};
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Переходные функции';
            app.uipanel3.Tag = 'uipanel3';
            app.axRoots = uiaxes(app.uipanel3);
            app.axRoots.Tag = 'axRoots';
            app.PushOut = uibutton(app.figure1, 'push');
            app.PushOut.ButtonPushedFcn = createCallbackFcn(app, @PushOut_Callback, true);
            app.PushOut.Tag = 'PushOut';
            app.PushOut.Text = 'Выход';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = metod_std_polynom_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)metod_std_polynom_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end